classdef Excavator_Design_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Excavator_Design                matlab.ui.Figure
        CalculateModelEditField         matlab.ui.control.EditField
        CalculateModelEditFieldLabel    matlab.ui.control.Label
        SetupModelEditField             matlab.ui.control.EditField
        SetupModelEditFieldLabel        matlab.ui.control.Label
        OpenCalculateModelButton        matlab.ui.control.Button
        OpenSetupModelButton            matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        DesignTab                       matlab.ui.container.Tab
        ViewinExcelButton               matlab.ui.control.Button
        LoadDesignandModifyPointsLabel  matlab.ui.control.Label
        DesignSelectDropDown            matlab.ui.control.DropDown
        ModelDesignPositionButton       matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        PlotDesignPositionButton        matlab.ui.control.Button
        MechPointsTable                 matlab.ui.control.Table
        UIAxesMechPoints                matlab.ui.control.UIAxes
        BOFTOFLiftCapacityTab           matlab.ui.container.Tab
        BucketMassLC1EditFieldLabel     matlab.ui.control.Label
        BucketMassLC1EditField          matlab.ui.control.NumericEditField
        StickMassLC1EditField           matlab.ui.control.NumericEditField
        BoomMassLC1EditField            matlab.ui.control.NumericEditField
        PartMasskgLC1Label              matlab.ui.control.Label
        StickMassLC1EditFieldLabel      matlab.ui.control.Label
        BoomMassLC1EditFieldLabel       matlab.ui.control.Label
        RodEndLabel                     matlab.ui.control.Label
        HeadEndLabel                    matlab.ui.control.Label
        BucketCylAreaRodEditField       matlab.ui.control.NumericEditField
        StickCylAreaRodEditField        matlab.ui.control.NumericEditField
        BoomCylAreaRodEditField         matlab.ui.control.NumericEditField
        BuckettoLinkageEditField        matlab.ui.control.NumericEditField
        BuckettoLinkageEditFieldLabel   matlab.ui.control.Label
        BucketCyltoLinkageEditField     matlab.ui.control.NumericEditField
        BucketCyltoLinkageEditFieldLabel  matlab.ui.control.Label
        BucketCyltoStickEditField       matlab.ui.control.NumericEditField
        BucketCyltoStickEditFieldLabel  matlab.ui.control.Label
        StickCyltoStickEditField        matlab.ui.control.NumericEditField
        StickCyltoStickEditFieldLabel   matlab.ui.control.Label
        StickCyltoBoomEditField         matlab.ui.control.NumericEditField
        StickCyltoBoomEditFieldLabel    matlab.ui.control.Label
        BoomCyltoChassisEditField       matlab.ui.control.NumericEditField
        BoomCyltoChassisEditFieldLabel  matlab.ui.control.Label
        BoomCyltoBoomEditField          matlab.ui.control.NumericEditField
        BoomCyltoBoomEditFieldLabel     matlab.ui.control.Label
        SticktoBucketEditField          matlab.ui.control.NumericEditField
        SticktoBucketEditFieldLabel     matlab.ui.control.Label
        SticktoLinkageEditField         matlab.ui.control.NumericEditField
        SticktoLinkageEditFieldLabel    matlab.ui.control.Label
        BoomtoStickEditField            matlab.ui.control.NumericEditField
        BoomtoStickEditFieldLabel       matlab.ui.control.Label
        PinLoadskNLabel                 matlab.ui.control.Label
        BoomtoChassisEditField          matlab.ui.control.NumericEditField
        BoomtoChassisEditFieldLabel     matlab.ui.control.Label
        BOFTOFTestCodeButton            matlab.ui.control.Button
        CylinderAreamm2Label            matlab.ui.control.Label
        BucketCylAreaHeadEditField      matlab.ui.control.NumericEditField
        BucketEditFieldLabel            matlab.ui.control.Label
        StickCylAreaHeadEditField       matlab.ui.control.NumericEditField
        StickEditFieldLabel             matlab.ui.control.Label
        BoomCylAreaHeadEditField        matlab.ui.control.NumericEditField
        BoomEditFieldLabel              matlab.ui.control.Label
        TestConfigurationLabel          matlab.ui.control.Label
        LoadkNLabel                     matlab.ui.control.Label
        ConfigureTestLabel              matlab.ui.control.Label
        ResultsofTestLabel              matlab.ui.control.Label
        BucketCylbarEditField           matlab.ui.control.NumericEditField
        BucketCylbarEditFieldLabel      matlab.ui.control.Label
        StickCylbarEditField            matlab.ui.control.NumericEditField
        StickCylbarEditFieldLabel       matlab.ui.control.Label
        BoomCylbarEditField             matlab.ui.control.NumericEditField
        BoomCylbarEditFieldLabel        matlab.ui.control.Label
        LoadkNEditField                 matlab.ui.control.NumericEditField
        TestDropDown                    matlab.ui.control.DropDown
        TestDropDownLabel               matlab.ui.control.Label
        ViewPositionButton              matlab.ui.control.Button
        RunTestButton                   matlab.ui.control.Button
        MaxPressuresbarLabel            matlab.ui.control.Label
        CylindersEditField              matlab.ui.control.NumericEditField
        CylindersEditFieldLabel         matlab.ui.control.Label
        PumpEditField                   matlab.ui.control.NumericEditField
        PumpEditFieldLabel              matlab.ui.control.Label
        BucketAngledegEditField         matlab.ui.control.NumericEditField
        BucketAngledegEditFieldLabel    matlab.ui.control.Label
        StickAngledegEditField          matlab.ui.control.NumericEditField
        StickAngledegEditFieldLabel     matlab.ui.control.Label
        BoomAngledegEditField           matlab.ui.control.NumericEditField
        BoomAngledegLabel               matlab.ui.control.Label
        BOFTOFImage                     matlab.ui.control.Image
        LoadChartTab                    matlab.ui.container.Tab
        BucketMassLCEditFieldLabel      matlab.ui.control.Label
        BucketMassLCEditField           matlab.ui.control.NumericEditField
        StickMassLCEditField            matlab.ui.control.NumericEditField
        BoomMassLCEditField             matlab.ui.control.NumericEditField
        PartMasskgLabel                 matlab.ui.control.Label
        StickMassLCEditFieldLabel       matlab.ui.control.Label
        BoomMassLCEditFieldLabel        matlab.ui.control.Label
        CylinderAreamm2Label_2          matlab.ui.control.Label
        BucketCylAreaLCEditField        matlab.ui.control.NumericEditField
        BucketCylAreaLCEditFieldLabel   matlab.ui.control.Label
        StickCylAreaLCEditField         matlab.ui.control.NumericEditField
        StickCylAreaLCEditFieldLabel    matlab.ui.control.Label
        BoomCylAreaLCEditField          matlab.ui.control.NumericEditField
        BoomCylAreaLCEditFieldLabel     matlab.ui.control.Label
        UseFastRestartCheckBox          matlab.ui.control.CheckBox
        LoadChartCodeButton             matlab.ui.control.Button
        BoomAngleRangeNumptsEditField   matlab.ui.control.NumericEditField
        StickAngleRangeNumptsEditField  matlab.ui.control.NumericEditField
        BucketAngleRangeNumptsEditField  matlab.ui.control.NumericEditField
        TestsLabel                      matlab.ui.control.Label
        BoomAngleRangeFinishEditField   matlab.ui.control.NumericEditField
        StickAngleRangeFinishEditField  matlab.ui.control.NumericEditField
        BucketAngleRangeFinishEditField  matlab.ui.control.NumericEditField
        StopLabel                       matlab.ui.control.Label
        degreesLabel                    matlab.ui.control.Label
        StartLabel                      matlab.ui.control.Label
        BucketAngleRangeStartEditField  matlab.ui.control.NumericEditField
        BucketAngleRangeLabel           matlab.ui.control.Label
        StickAngleRangeStartEditField   matlab.ui.control.NumericEditField
        StickAngleRangeEditFieldLabel   matlab.ui.control.Label
        BoomAngleRangeStartEditField    matlab.ui.control.NumericEditField
        BoomAngleRangeEditFieldLabel    matlab.ui.control.Label
        LoadChartStickBucketCylindersEditField  matlab.ui.control.NumericEditField
        StickBucketCylindersEditFieldLabel  matlab.ui.control.Label
        LoadChartBoomCylinderEditField  matlab.ui.control.NumericEditField
        BoomCylinderLabel               matlab.ui.control.Label
        GenerateLoadChartButton         matlab.ui.control.Button
        LoadChartMaxPressuresbarLabel   matlab.ui.control.Label
        LoadChartUIAxes                 matlab.ui.control.UIAxes
        ExcavatorDesignSolutionLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.MechPointsTable = uitable(app.DesignTab);
            [~, Pin_Locations] = Excavator_Pin_Locations_global('Design A');
            app.MechPointsTable.Data = Pin_Locations;
            app.MechPointsTable.ColumnName = Pin_Locations.Properties.VariableNames;
            app.MechPointsTable.ColumnEditable = [false false false true true];
            app.MechPointsTable.ColumnWidth = {45,60,'auto',50,50};
            ChassisBkg = [255 221 221]/256;
            BoomBkg = [201 255 225]/256;
            StickBkg = [209 243 255]/256;
            LinkageBkg = [255 238 183]/256;
            BucketBkg = [230 213 243]/256;
            app.MechPointsTable.BackgroundColor = ...
                [ChassisBkg;ChassisBkg;...
                BoomBkg;BoomBkg;BoomBkg;...
                StickBkg;StickBkg;StickBkg;StickBkg;...
                LinkageBkg;...
                BucketBkg;BucketBkg;BucketBkg];
            ExcvGlobal = Excavator_Pin_Locations_table2struct(app.MechPointsTable.Data);
            Excavator_Pin_Locations_plotGlobal(ExcvGlobal,app.UIAxesMechPoints)
        end

        % Button pushed function: ViewPositionButton
        function ViewPositionButtonPushed(app, event)
            boomAngle = app.BoomAngledegEditField.Value;
            stickAngle = app.StickAngledegEditField.Value;
            bucketAngle = app.BucketAngledegEditField.Value;
            assignin('base','BOFTOF_boomAngle',boomAngle);
            assignin('base','BOFTOF_stickAngle',stickAngle);
            assignin('base','BOFTOF_bucketAngle',bucketAngle);
            load_system(app.SetupModelEditField.Value);
            sim(app.SetupModelEditField.Value);
        end

        % Button pushed function: RunTestButton
        function RunTestButtonPushed(app, event)
            %From ViewPositionButtonPushed - need values in workspace
            boomAngle = app.BoomAngledegEditField.Value;
            stickAngle = app.StickAngledegEditField.Value;
            bucketAngle = app.BucketAngledegEditField.Value;
            assignin('base','BOFTOF_boomAngle',boomAngle);
            assignin('base','BOFTOF_stickAngle',stickAngle);
            assignin('base','BOFTOF_bucketAngle',bucketAngle);
            assignin('base','forceTestMaxActiveCylPr',app.PumpEditField.Value)
            assignin('base','forceTestMaxReactiveCylPr',app.CylindersEditField.Value)
            testType = app.TestDropDown.Value;
            switch testType
                case {'BOF','TOF'}
                    assignin('base','BOFTOF_BoomCylArea',app.BoomCylAreaRodEditField.Value)
                    assignin('base','BOFTOF_StickCylArea',app.StickCylAreaHeadEditField.Value)
                    assignin('base','BOFTOF_BucketCylArea',app.BucketCylAreaHeadEditField.Value)
                case {'Lift'}
                    assignin('base','BOFTOF_BoomCylArea',app.BoomCylAreaHeadEditField.Value)
                    assignin('base','BOFTOF_StickCylArea',app.StickCylAreaRodEditField.Value)
                    assignin('base','BOFTOF_BucketCylArea',app.BucketCylAreaRodEditField.Value)
                    assignin('base','LoadChart_BoomMass',app.BoomMassLC1EditField.Value);
                    assignin('base','LoadChart_StickMass',app.StickMassLC1EditField.Value);
                    assignin('base','LoadChart_BucketMass',app.BucketMassLC1EditField.Value);
            end
            setup_mdl = app.SetupModelEditField.Value;
            calc_mdl  = app.CalculateModelEditField.Value;
            str = ['[fLoad,test_type,pBoom,pStick,pBucket,pinForces]= Excavator_RunBOFTOF(''' setup_mdl ''',''' calc_mdl ''',''' testType ''');'];
            %str = ['SelectScenarioDefinition(''' testType ''',Scenario);'];
            disp(str);
            [fLoad,test_type,pBoom,pStick,pBucket,pinForces]= Excavator_RunBOFTOF(setup_mdl,calc_mdl,testType);
            ExcvGlobal = evalin('base','ExcvGlobal_BOFTOFPos');
            Excavator_BOFTOFLoad_plot(ExcvGlobal,fLoad,test_type,pBoom,pStick,pBucket,pinForces);
            %open_system(app.CalculateModelEditField.Value);
            %sim(app.CalculateModelEditField.Value);
            app.LoadkNEditField.Value = fLoad;
            app.LoadkNLabel.Text = [test_type ' (kN)'];
            app.BoomCylbarEditField.Value = pBoom;
            app.StickCylbarEditField.Value = pStick;
            app.BucketCylbarEditField.Value = pBucket;

            app.BoomtoChassisEditField.Value      = pinForces.Boom_To_Chassis;
            app.BoomtoStickEditField.Value        = pinForces.Boom_To_Stick;
            app.SticktoLinkageEditField.Value     = pinForces.Stick_To_Linkage;
            app.SticktoBucketEditField.Value      = pinForces.Stick_To_Bucket;
            app.BoomCyltoBoomEditField.Value      = pinForces.Boom_Cylinder_To_Boom;
            app.BoomCyltoChassisEditField.Value   = pinForces.Boom_Cylinder_To_Chassis;
            app.StickCyltoBoomEditField.Value     = pinForces.Stick_Cylinder_To_Boom;
            app.StickCyltoStickEditField.Value    = pinForces.Stick_Cylinder_To_Stick;
            app.BucketCyltoStickEditField.Value   = pinForces.Bucket_Cylinder_To_Stick;
            app.BucketCyltoLinkageEditField.Value = pinForces.Bucket_Cylinder_To_Linkage;
            app.BuckettoLinkageEditField.Value    = pinForces.Bucket_To_Linkage;

        end

        % Value changed function: TestDropDown
        function TestDropDownValueChanged(app, event)
            value = app.TestDropDown.Value;
            switch value
                case 'BOF'
                    app.BOFTOFImage.ImageSource = 'ExcavatorUI_BOF_Test.png';
                    app.BoomCylAreaRodEditField.Editable = "on";
                    app.BoomCylAreaRodEditField.FontColor = [0 0 0];
                    app.StickCylAreaHeadEditField.Editable = "on";
                    app.StickCylAreaHeadEditField.FontColor = [0 0 0];
                    app.BucketCylAreaHeadEditField.Editable = "on";
                    app.BucketCylAreaHeadEditField.FontColor = [0 0 0];
                    app.BoomCylAreaHeadEditField.Editable = "off";
                    app.BoomCylAreaHeadEditField.FontColor = [0.65 0.65 0.65];
                    app.StickCylAreaRodEditField.Editable = "off";
                    app.StickCylAreaRodEditField.FontColor = [0.65 0.65 0.65];
                    app.BucketCylAreaRodEditField.Editable = "off";
                    app.BucketCylAreaRodEditField.FontColor = [0.65 0.65 0.65];
                    app.BoomCylAreaRodEditField.Editable = "on";
                    app.BoomCylAreaRodEditField.FontColor = [0 0 0];
                    app.BoomMassLC1EditField.Editable = "off";
                    app.BoomMassLC1EditField.FontColor = [0.65 0.65 0.65];
                    app.StickMassLC1EditField.Editable = "off";
                    app.StickMassLC1EditField.FontColor = [0.65 0.65 0.65];
                    app.BucketMassLC1EditField.Editable = "off";
                    app.BucketMassLC1EditField.FontColor = [0.65 0.65 0.65];
                case 'TOF'
                    app.BOFTOFImage.ImageSource = 'ExcavatorUI_TOF_Test.png';
                    app.BoomCylAreaRodEditField.Editable = "on";
                    app.BoomCylAreaRodEditField.FontColor = [0 0 0];
                    app.StickCylAreaHeadEditField.Editable = "on";
                    app.StickCylAreaHeadEditField.FontColor = [0 0 0];
                    app.BucketCylAreaHeadEditField.Editable = "on";
                    app.BucketCylAreaHeadEditField.FontColor = [0 0 0];
                    app.BoomCylAreaHeadEditField.Editable = "off";
                    app.BoomCylAreaHeadEditField.FontColor = [0.65 0.65 0.65];
                    app.StickCylAreaRodEditField.Editable = "off";
                    app.StickCylAreaRodEditField.FontColor = [0.65 0.65 0.65];
                    app.BucketCylAreaRodEditField.Editable = "off";
                    app.BucketCylAreaRodEditField.FontColor = [0.65 0.65 0.65];
                    app.BoomMassLC1EditField.Editable = "off";
                    app.BoomMassLC1EditField.FontColor = [0.65 0.65 0.65];
                    app.StickMassLC1EditField.Editable = "off";
                    app.StickMassLC1EditField.FontColor = [0.65 0.65 0.65];
                    app.BucketMassLC1EditField.Editable = "off";
                    app.BucketMassLC1EditField.FontColor = [0.65 0.65 0.65];
                    
                case 'Lift'
                    app.BOFTOFImage.ImageSource = 'ExcavatorUI_LoadChart_Test.png';
                    app.BoomCylAreaRodEditField.Editable = "off";
                    app.BoomCylAreaRodEditField.FontColor = [0.65 0.65 0.65];
                    app.StickCylAreaHeadEditField.Editable = "off";
                    app.StickCylAreaHeadEditField.FontColor = [0.65 0.65 0.65];
                    app.BucketCylAreaHeadEditField.Editable = "off";
                    app.BucketCylAreaHeadEditField.FontColor = [0.65 0.65 0.65];
                    app.BoomCylAreaHeadEditField.Editable = "on";
                    app.BoomCylAreaHeadEditField.FontColor = [0 0 0];
                    app.StickCylAreaRodEditField.Editable = "on";
                    app.StickCylAreaRodEditField.FontColor = [0 0 0];
                    app.BucketCylAreaRodEditField.Editable = "on";
                    app.BucketCylAreaRodEditField.FontColor = [0 0 0];
                    app.BoomMassLC1EditField.Editable = "on";
                    app.BoomMassLC1EditField.FontColor = [0 0 0];
                    app.StickMassLC1EditField.Editable = "on";
                    app.StickMassLC1EditField.FontColor = [0 0 0];
                    app.BucketMassLC1EditField.Editable = "on";
                    app.BucketMassLC1EditField.FontColor = [0 0 0];
                    
            end
        end

        % Button pushed function: PlotDesignPositionButton
        function PlotDesignPositionButtonPushed(app, event)
            ExcvGlobalUI = Excavator_Pin_Locations_table2struct(app.MechPointsTable.Data);
            Excavator_Pin_Locations_plotGlobal(ExcvGlobalUI,app.UIAxesMechPoints)
            assignin('base','ExcvGlobalUI',ExcvGlobalUI)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            sheetName = strrep(app.DesignSelectDropDown.Value,'_',' ');
            [~, PinLocTable] = Excavator_Pin_Locations_global(sheetName);
            app.MechPointsTable.Data = PinLocTable;
        end

        % Button pushed function: ModelDesignPositionButton
        function ModelDesignPositionButtonPushed(app, event)
            ExcvGlobalUI = Excavator_Pin_Locations_table2struct(app.MechPointsTable.Data);
            assignin('base','ExcvGlobalUI',ExcvGlobalUI);
            ExcvLocal  = Excavator_Pin_Locations_global2local(ExcvGlobalUI);
            assignin('base','ExcvLocal',ExcvLocal);
            [boomAngle, stickAngle, bucketAngle] = Excavator_getDesignPosition(ExcvGlobalUI);
            assignin('base','BOFTOF_boomAngle',boomAngle);
            assignin('base','BOFTOF_stickAngle',stickAngle);
            assignin('base','BOFTOF_bucketAngle',bucketAngle);
            setupModel = app.SetupModelEditField.Value;
            if(endsWith(app.SetupModelEditField.Value,'.slx'))
                setupModel = app.SetupModelEditField.Value(1:end-4);
            end
            load_system([setupModel '.slx']);
            str = ['set_param(''' setupModel ''', ''SimulationCommand'', ''update'');'];
            disp(str)
            evalin('base',str)
        end

        % Button pushed function: OpenSetupModelButton
        function OpenSetupModelButtonPushed(app, event)
            open_system(app.SetupModelEditField.Value);
        end

        % Button pushed function: OpenCalculateModelButton
        function OpenCalculateModelButtonPushed(app, event)
            open_system(app.CalculateModelEditField.Value);
        end

        % Button pushed function: ViewinExcelButton
        function ViewinExcelButtonPushed(app, event)
            if(ispc)
                winopen('Excavator_Pin_Locations_global.xlsx')
            end
        end

        % Button pushed function: GenerateLoadChartButton
        function GenerateLoadChartButtonPushed(app, event)
            qBoomStart = app.BoomAngleRangeStartEditField.Value;
            qBoomFinish = app.BoomAngleRangeFinishEditField.Value;
            qBoomNpts = app.BoomAngleRangeNumptsEditField.Value;
            qStickStart = app.StickAngleRangeStartEditField.Value;
            qStickFinish = app.StickAngleRangeFinishEditField.Value;
            qStickNpts = app.StickAngleRangeNumptsEditField.Value;
            qBucketStart = app.BucketAngleRangeStartEditField.Value;
            qBucketFinish = app.BucketAngleRangeFinishEditField.Value;
            qBucketNpts = app.BucketAngleRangeNumptsEditField.Value;
            qBoomPts = linspace(qBoomStart,qBoomFinish,qBoomNpts);
            qStickPts = linspace(qStickStart,qStickFinish,qStickNpts);
            qBucketPts = linspace(qBucketStart,qBucketFinish,qBucketNpts);
            assignin('base','forceTestMaxActiveCylPr',app.LoadChartBoomCylinderEditField.Value)
            assignin('base','forceTestMaxReactiveCylPr',app.LoadChartStickBucketCylindersEditField.Value)            
            assignin('base','BOFTOF_BoomCylArea',app.BoomCylAreaLCEditField.Value);
            assignin('base','BOFTOF_StickCylArea',app.StickCylAreaLCEditField.Value);
            assignin('base','BOFTOF_BucketCylArea',app.BucketCylAreaLCEditField.Value);
            assignin('base','LoadChart_BoomMass',app.BoomMassLCEditField.Value);
            assignin('base','LoadChart_StickMass',app.StickMassLCEditField.Value);
            assignin('base','LoadChart_BucketMass',app.BucketMassLCEditField.Value);
            setupMdl = app.SetupModelEditField.Value;
            calcMdl = app.CalculateModelEditField.Value;
            useFastRestart = app.UseFastRestartCheckBox.Value;
            Excavator_LoadChart_Create(qBoomPts,qStickPts,qBucketPts,setupMdl,calcMdl,useFastRestart,app.LoadChartUIAxes);
        end

        % Button pushed function: LoadChartCodeButton
        function LoadChartCodeButtonPushed(app, event)
            edit('Excavator_LoadChart_Create.m')
        end

        % Button pushed function: BOFTOFTestCodeButton
        function BOFTOFTestCodeButtonPushed(app, event)
            edit('Excavator_RunBOFTOF.m')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Excavator_Design = uifigure('Visible', 'off');
            app.Excavator_Design.Name = 'Excavator Design';
            app.ExcavatorDesignSolutionLabel = uilabel(app.Excavator_Design);
            app.ExcavatorDesignSolutionLabel.Text = 'Excavator Design with Simscape: Calculate Break Out Force (BOF), Tear Out Force (TOF), Lift Capacity';
            app.TabGroup = uitabgroup(app.Excavator_Design);
            app.DesignTab = uitab(app.TabGroup);
            app.DesignTab.Tooltip = {'Define pin locations.'};
            app.DesignTab.Title = 'Design';
            app.UIAxesMechPoints = uiaxes(app.DesignTab);
            title(app.UIAxesMechPoints, 'Excavator Pin Locations')
            xlabel(app.UIAxesMechPoints, 'X')
            ylabel(app.UIAxesMechPoints, 'Y')
            zlabel(app.UIAxesMechPoints, 'Z')
            app.MechPointsTable = uitable(app.DesignTab);
            app.MechPointsTable.ColumnName = '';
            app.MechPointsTable.RowName = {};
            app.PlotDesignPositionButton = uibutton(app.DesignTab, 'push');
            app.PlotDesignPositionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDesignPositionButtonPushed, true);
            app.PlotDesignPositionButton.Tooltip = {'Plot excavator with pin locations.'};
            app.PlotDesignPositionButton.Text = 'Plot Design Position';
            app.LoadButton = uibutton(app.DesignTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load pin locations into App'};
            app.LoadButton.Text = 'Load ';
            app.ModelDesignPositionButton = uibutton(app.DesignTab, 'push');
            app.ModelDesignPositionButton.ButtonPushedFcn = createCallbackFcn(app, @ModelDesignPositionButtonPushed, true);
            app.ModelDesignPositionButton.Tooltip = {'Show model visualization with pin locations. Pin locations are loaded into MATLAB Workspace.'};
            app.ModelDesignPositionButton.Text = 'Model Design Position';
            app.DesignSelectDropDown = uidropdown(app.DesignTab);
            app.DesignSelectDropDown.Items = {'Design A', 'Design B'};
            app.DesignSelectDropDown.ItemsData = {'Design_A', 'Design_B'};
            app.DesignSelectDropDown.Tooltip = {'Name of Excel sheet with pin locations'};
            app.DesignSelectDropDown.Value = 'Design_A';
            app.LoadDesignandModifyPointsLabel = uilabel(app.DesignTab);
            app.LoadDesignandModifyPointsLabel.Text = 'Load Design and Modify Points';
            app.ViewinExcelButton = uibutton(app.DesignTab, 'push');
            app.ViewinExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ViewinExcelButtonPushed, true);
            app.ViewinExcelButton.Tooltip = {'Open Excel file.'};
            app.ViewinExcelButton.Text = 'View in Excel';
            app.BOFTOFLiftCapacityTab = uitab(app.TabGroup);
            app.BOFTOFLiftCapacityTab.Title = 'BOF, TOF, Lift Capacity';
            app.BOFTOFImage = uiimage(app.BOFTOFLiftCapacityTab);
            app.BOFTOFImage.ImageSource = 'ExcavatorUI_BOF_Test.png';
            app.BoomAngledegLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.BoomAngledegLabel.Tooltip = {'See diagram for boom angle definition.'};
            app.BoomAngledegLabel.Text = 'Boom Angle (deg)';
            app.BoomAngledegEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BoomAngledegEditField.Tooltip = {'See diagram for boom angle definition.'};
            app.BoomAngledegEditField.Value = 90;
            app.StickAngledegEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.StickAngledegEditFieldLabel.Tooltip = {'See diagram for stick angle definition.'};
            app.StickAngledegEditFieldLabel.Text = 'Stick Angle (deg)';
            app.StickAngledegEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.StickAngledegEditField.Tooltip = {'See diagram for stick angle definition.'};
            app.StickAngledegEditField.Value = 90;
            app.BucketAngledegEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.BucketAngledegEditFieldLabel.Tooltip = {'See diagram for bucket angle definition.'};
            app.BucketAngledegEditFieldLabel.Text = 'Bucket Angle (deg)';
            app.BucketAngledegEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BucketAngledegEditField.Tooltip = {'See diagram for bucket angle definition.'};
            app.BucketAngledegEditField.Value = 90;
            app.PumpEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.PumpEditFieldLabel.Tooltip = {'Max pressure for active cylinder is pump relief pressure.'};
            app.PumpEditFieldLabel.Text = 'Pump';
            app.PumpEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.PumpEditField.Tooltip = {'Max pressure for active cylinder is pump relief pressure.'};
            app.PumpEditField.Value = 350;
            app.CylindersEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.CylindersEditFieldLabel.Tooltip = {'Max pressure for reactive cylinder is cylinder relief pressure.'};
            app.CylindersEditFieldLabel.Text = 'Cylinders';
            app.CylindersEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.CylindersEditField.Tooltip = {'Max pressure for reactive cylinder is cylinder relief pressure.'};
            app.CylindersEditField.Value = 400;
            app.MaxPressuresbarLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.MaxPressuresbarLabel.Text = 'Max Pressures (bar)';
            app.RunTestButton = uibutton(app.BOFTOFLiftCapacityTab, 'push');
            app.RunTestButton.ButtonPushedFcn = createCallbackFcn(app, @RunTestButtonPushed, true);
            app.RunTestButton.Tooltip = {'Run selected test. Pressures and areas will be written to MATLAB workspace.'};
            app.RunTestButton.Text = 'Run Test';
            app.ViewPositionButton = uibutton(app.BOFTOFLiftCapacityTab, 'push');
            app.ViewPositionButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPositionButtonPushed, true);
            app.ViewPositionButton.Tooltip = {'Show test position in model. Test angles written to MATLAB workspace.'};
            app.ViewPositionButton.Text = {'View'; 'Position'};
            app.TestDropDownLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.TestDropDownLabel.Text = 'Test';
            app.TestDropDown = uidropdown(app.BOFTOFLiftCapacityTab);
            app.TestDropDown.Items = {'BOF', 'TOF', 'Lift'};
            app.TestDropDown.ItemsData = {'BOF', 'TOF', 'Lift'};
            app.TestDropDown.ValueChangedFcn = createCallbackFcn(app, @TestDropDownValueChanged, true);
            app.TestDropDown.Tooltip = {'Select test. Calculate model will be reconfigured.'};
            app.TestDropDown.Value = 'BOF';
            app.LoadkNEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.LoadkNEditField.Editable = 'off';
            app.BoomCylbarEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.BoomCylbarEditFieldLabel.Text = 'Boom Cyl. (bar)';
            app.BoomCylbarEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BoomCylbarEditField.Editable = 'off';
            app.StickCylbarEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.StickCylbarEditFieldLabel.Text = 'Stick Cyl. (bar)';
            app.StickCylbarEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.StickCylbarEditField.Editable = 'off';
            app.BucketCylbarEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.BucketCylbarEditFieldLabel.Text = 'Bucket Cyl. (bar)';
            app.BucketCylbarEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BucketCylbarEditField.Editable = 'off';
            app.ResultsofTestLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.ResultsofTestLabel.Text = 'Results of Test';
            app.ConfigureTestLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.ConfigureTestLabel.Text = 'Configure Test';
            app.LoadkNLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.LoadkNLabel.Text = 'Load (kN)';
            app.TestConfigurationLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.TestConfigurationLabel.Text = 'Test Configuration';
            app.BoomEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.BoomEditFieldLabel.Tooltip = {'Area of boom cylinder (only one). Rod end for BOF, TOF, head end for lift capacity.'};
            app.BoomEditFieldLabel.Text = 'Boom ';
            app.BoomCylAreaHeadEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BoomCylAreaHeadEditField.Limits = [0 Inf];
            app.BoomCylAreaHeadEditField.ValueDisplayFormat = '%11.0f';
            app.BoomCylAreaHeadEditField.Editable = 'off';
            app.BoomCylAreaHeadEditField.Tooltip = {'Area of boom cylinder (only one). Rod end for BOF, TOF, head end for lift capacity.'};
            app.BoomCylAreaHeadEditField.Value = 38013;
            app.StickEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.StickEditFieldLabel.Tooltip = {'Area of stick cylinder. Head end for BOF, TOF, rod end for lift capacity.'};
            app.StickEditFieldLabel.Text = 'Stick ';
            app.StickCylAreaHeadEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.StickCylAreaHeadEditField.Limits = [0 Inf];
            app.StickCylAreaHeadEditField.ValueDisplayFormat = '%11.0f';
            app.StickCylAreaHeadEditField.Editable = 'off';
            app.StickCylAreaHeadEditField.Tooltip = {'Area of stick cylinder. Head end for BOF, TOF, rod end for lift capacity.'};
            app.StickCylAreaHeadEditField.Value = 49087;
            app.BucketEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.BucketEditFieldLabel.Tooltip = {'Area of bucket cylinder. Head end for BOF, TOF, rod end for lift capacity.'};
            app.BucketEditFieldLabel.Text = 'Bucket ';
            app.BucketCylAreaHeadEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BucketCylAreaHeadEditField.Limits = [0 Inf];
            app.BucketCylAreaHeadEditField.ValueDisplayFormat = '%11.0f';
            app.BucketCylAreaHeadEditField.Tooltip = {'Area of bucket cylinder. Head end for BOF, TOF, rod end for lift capacity.'};
            app.BucketCylAreaHeadEditField.Value = 38013;
            app.CylinderAreamm2Label = uilabel(app.BOFTOFLiftCapacityTab);
            app.CylinderAreamm2Label.Text = 'Cylinder Area (mm^2)';
            app.BOFTOFTestCodeButton = uibutton(app.BOFTOFLiftCapacityTab, 'push');
            app.BOFTOFTestCodeButton.ButtonPushedFcn = createCallbackFcn(app, @BOFTOFTestCodeButtonPushed, true);
            app.BOFTOFTestCodeButton.Tooltip = {'See code used to run test.'};
            app.BOFTOFTestCodeButton.Text = 'Code';
            app.BoomtoChassisEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.BoomtoChassisEditFieldLabel.Text = 'Boom to Chassis';
            app.BoomtoChassisEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BoomtoChassisEditField.ValueDisplayFormat = '%.1f';
            app.BoomtoChassisEditField.Editable = 'off';
            app.PinLoadskNLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.PinLoadskNLabel.Text = 'Pin Loads (kN)';
            app.BoomtoStickEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.BoomtoStickEditFieldLabel.Text = 'Boom to Stick';
            app.BoomtoStickEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BoomtoStickEditField.ValueDisplayFormat = '%.1f';
            app.BoomtoStickEditField.Editable = 'off';
            app.SticktoLinkageEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.SticktoLinkageEditFieldLabel.Text = 'Stick to Linkage';
            app.SticktoLinkageEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.SticktoLinkageEditField.ValueDisplayFormat = '%.1f';
            app.SticktoLinkageEditField.Editable = 'off';
            app.SticktoBucketEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.SticktoBucketEditFieldLabel.Text = 'Stick to Bucket';
            app.SticktoBucketEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.SticktoBucketEditField.ValueDisplayFormat = '%.1f';
            app.SticktoBucketEditField.Editable = 'off';
            app.BoomCyltoBoomEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.BoomCyltoBoomEditFieldLabel.Text = 'Boom Cyl. to Boom';
            app.BoomCyltoBoomEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BoomCyltoBoomEditField.ValueDisplayFormat = '%.1f';
            app.BoomCyltoBoomEditField.Editable = 'off';
            app.BoomCyltoChassisEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.BoomCyltoChassisEditFieldLabel.Text = 'Boom Cyl. to Chassis';
            app.BoomCyltoChassisEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BoomCyltoChassisEditField.ValueDisplayFormat = '%.1f';
            app.BoomCyltoChassisEditField.Editable = 'off';
            app.StickCyltoBoomEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.StickCyltoBoomEditFieldLabel.Text = 'Stick Cyl. to Boom';
            app.StickCyltoBoomEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.StickCyltoBoomEditField.ValueDisplayFormat = '%.1f';
            app.StickCyltoBoomEditField.Editable = 'off';
            app.StickCyltoStickEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.StickCyltoStickEditFieldLabel.Text = 'Stick Cyl. to Stick';
            app.StickCyltoStickEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.StickCyltoStickEditField.ValueDisplayFormat = '%.1f';
            app.StickCyltoStickEditField.Editable = 'off';
            app.BucketCyltoStickEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.BucketCyltoStickEditFieldLabel.Text = 'Bucket Cyl. to Stick';
            app.BucketCyltoStickEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BucketCyltoStickEditField.ValueDisplayFormat = '%.1f';
            app.BucketCyltoStickEditField.Editable = 'off';
            app.BucketCyltoLinkageEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.BucketCyltoLinkageEditFieldLabel.Text = 'Bucket Cyl. to Linkage';
            app.BucketCyltoLinkageEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BucketCyltoLinkageEditField.ValueDisplayFormat = '%.1f';
            app.BucketCyltoLinkageEditField.Editable = 'off';
            app.BuckettoLinkageEditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.BuckettoLinkageEditFieldLabel.Text = 'Bucket to Linkage';
            app.BuckettoLinkageEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BuckettoLinkageEditField.ValueDisplayFormat = '%.1f';
            app.BuckettoLinkageEditField.Editable = 'off';
            app.BoomCylAreaRodEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BoomCylAreaRodEditField.ValueDisplayFormat = '%11.0f';
            app.BoomCylAreaRodEditField.Tooltip = {'Area of boom cylinder (only one). Rod end for BOF, TOF, head end for lift capacity.'};
            app.BoomCylAreaRodEditField.Value = 20341;
            app.StickCylAreaRodEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.StickCylAreaRodEditField.Limits = [0 Inf];
            app.StickCylAreaRodEditField.ValueDisplayFormat = '%11.0f';
            app.StickCylAreaRodEditField.Tooltip = {'Area of stick cylinder. Head end for BOF, TOF, rod end for lift capacity.'};
            app.StickCylAreaRodEditField.Value = 26389;
            app.BucketCylAreaRodEditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BucketCylAreaRodEditField.Limits = [0 Inf];
            app.BucketCylAreaRodEditField.ValueDisplayFormat = '%11.0f';
            app.BucketCylAreaRodEditField.Editable = 'off';
            app.BucketCylAreaRodEditField.Tooltip = {'Area of bucket cylinder. Head end for BOF, TOF, rod end for lift capacity.'};
            app.BucketCylAreaRodEditField.Value = 17907;
            app.HeadEndLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.HeadEndLabel.Text = 'Head End';
            app.RodEndLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.RodEndLabel.Text = 'Rod End';
            app.BoomMassLC1EditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.BoomMassLC1EditFieldLabel.Text = 'Boom ';
            app.StickMassLC1EditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.StickMassLC1EditFieldLabel.Text = 'Stick ';
            app.PartMasskgLC1Label = uilabel(app.BOFTOFLiftCapacityTab);
            app.PartMasskgLC1Label.Text = 'Part Mass (kg)';
            app.BoomMassLC1EditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BoomMassLC1EditField.Limits = [0 Inf];
            app.BoomMassLC1EditField.ValueDisplayFormat = '%11.2f';
            app.BoomMassLC1EditField.Editable = 'off';
            app.BoomMassLC1EditField.Tooltip = {'Mass of boom.'};
            app.BoomMassLC1EditField.Value = 7226.51;
            app.StickMassLC1EditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.StickMassLC1EditField.Limits = [0 Inf];
            app.StickMassLC1EditField.ValueDisplayFormat = '%11.2f';
            app.StickMassLC1EditField.Editable = 'off';
            app.StickMassLC1EditField.Tooltip = {'Mass of stick.'};
            app.StickMassLC1EditField.Value = 3327.88;
            app.BucketMassLC1EditField = uieditfield(app.BOFTOFLiftCapacityTab, 'numeric');
            app.BucketMassLC1EditField.Limits = [0 Inf];
            app.BucketMassLC1EditField.ValueDisplayFormat = '%11.2f';
            app.BucketMassLC1EditField.Editable = 'off';
            app.BucketMassLC1EditField.Tooltip = {'Mass of empty bucket. '; ' Load is added during test.'};
            app.BucketMassLC1EditField.Value = 5119.51;
            app.BucketMassLC1EditFieldLabel = uilabel(app.BOFTOFLiftCapacityTab);
            app.BucketMassLC1EditFieldLabel.Text = 'Bucket ';
            app.LoadChartTab = uitab(app.TabGroup);
            app.LoadChartTab.Title = 'Load Chart';
            app.LoadChartUIAxes = uiaxes(app.LoadChartTab);
            title(app.LoadChartUIAxes, 'Load Chart')
            xlabel(app.LoadChartUIAxes, 'X')
            ylabel(app.LoadChartUIAxes, 'Y')
            zlabel(app.LoadChartUIAxes, 'Z')
            app.LoadChartMaxPressuresbarLabel = uilabel(app.LoadChartTab);
            app.LoadChartMaxPressuresbarLabel.Text = 'Load Chart Max Pressures (bar)';
            app.GenerateLoadChartButton = uibutton(app.LoadChartTab, 'push');
            app.GenerateLoadChartButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateLoadChartButtonPushed, true);
            app.GenerateLoadChartButton.Text = 'Generate Load Chart';
            app.BoomCylinderLabel = uilabel(app.LoadChartTab);
            app.BoomCylinderLabel.Tooltip = {'Max pressure for active cylinder (boom) is pump relief pressure.'};
            app.BoomCylinderLabel.Text = 'Boom Cylinder';
            app.LoadChartBoomCylinderEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.LoadChartBoomCylinderEditField.Tooltip = {'Max pressure for active cylinder (boom) is pump relief pressure.'};
            app.LoadChartBoomCylinderEditField.Value = 350;
            app.StickBucketCylindersEditFieldLabel = uilabel(app.LoadChartTab);
            app.StickBucketCylindersEditFieldLabel.Tooltip = {'Max pressure for reactive cylinders (stick, bucket) is cylinder relief pressure.'};
            app.StickBucketCylindersEditFieldLabel.Text = 'Stick, Bucket Cylinders';
            app.LoadChartStickBucketCylindersEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.LoadChartStickBucketCylindersEditField.Tooltip = {'Max pressure for reactive cylinders (stick, bucket) is cylinder relief pressure.'};
            app.LoadChartStickBucketCylindersEditField.Value = 400;
            app.BoomAngleRangeEditFieldLabel = uilabel(app.LoadChartTab);
            app.BoomAngleRangeEditFieldLabel.Text = 'Boom Angle Range';
            app.BoomAngleRangeStartEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.BoomAngleRangeStartEditField.Value = 60;
            app.StickAngleRangeEditFieldLabel = uilabel(app.LoadChartTab);
            app.StickAngleRangeEditFieldLabel.Text = 'Stick Angle Range';
            app.StickAngleRangeStartEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.StickAngleRangeStartEditField.Value = 120;
            app.BucketAngleRangeLabel = uilabel(app.LoadChartTab);
            app.BucketAngleRangeLabel.Text = 'Bucket Angle Range';
            app.BucketAngleRangeStartEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.BucketAngleRangeStartEditField.Value = 90;
            app.StartLabel = uilabel(app.LoadChartTab);
            app.StartLabel.Text = 'Start';
            app.degreesLabel = uilabel(app.LoadChartTab);
            app.degreesLabel.Text = '(degrees)';
            app.StopLabel = uilabel(app.LoadChartTab);
            app.StopLabel.Text = 'Stop';
            app.BucketAngleRangeFinishEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.BucketAngleRangeFinishEditField.Value = 60;
            app.StickAngleRangeFinishEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.StickAngleRangeFinishEditField.Value = 45;
            app.BoomAngleRangeFinishEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.BoomAngleRangeFinishEditField.Value = 120;
            app.TestsLabel = uilabel(app.LoadChartTab);
            app.TestsLabel.Text = '# Tests';
            app.BucketAngleRangeNumptsEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.BucketAngleRangeNumptsEditField.Value = 3;
            app.StickAngleRangeNumptsEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.StickAngleRangeNumptsEditField.Value = 5;
            app.BoomAngleRangeNumptsEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.BoomAngleRangeNumptsEditField.Value = 10;
            app.LoadChartCodeButton = uibutton(app.LoadChartTab, 'push');
            app.LoadChartCodeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadChartCodeButtonPushed, true);
            app.LoadChartCodeButton.Text = 'Code';
            app.UseFastRestartCheckBox = uicheckbox(app.LoadChartTab);
            app.UseFastRestartCheckBox.Tooltip = {'If Fast Restart is enabled, sweep runs faster but animation is not shown.'};
            app.UseFastRestartCheckBox.Text = 'Use Fast Restart';
            app.UseFastRestartCheckBox.Value = true;
            app.BoomCylAreaLCEditFieldLabel = uilabel(app.LoadChartTab);
            app.BoomCylAreaLCEditFieldLabel.Tooltip = {'Area of boom cylinder (only one), head end.'};
            app.BoomCylAreaLCEditFieldLabel.Text = 'Boom (head)';
            app.BoomCylAreaLCEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.BoomCylAreaLCEditField.ValueDisplayFormat = '%11.0f';
            app.BoomCylAreaLCEditField.Tooltip = {'Area of boom cylinder (only one), head end.'};
            app.BoomCylAreaLCEditField.Value = 38013;
            app.StickCylAreaLCEditFieldLabel = uilabel(app.LoadChartTab);
            app.StickCylAreaLCEditFieldLabel.Tooltip = {'Area of stick cylinder, rod end'};
            app.StickCylAreaLCEditFieldLabel.Text = 'Stick  (rod)';
            app.StickCylAreaLCEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.StickCylAreaLCEditField.Limits = [0 Inf];
            app.StickCylAreaLCEditField.ValueDisplayFormat = '%11.0f';
            app.StickCylAreaLCEditField.Tooltip = {'Area of stick cylinder, rod end'};
            app.StickCylAreaLCEditField.Value = 26389;
            app.BucketCylAreaLCEditFieldLabel = uilabel(app.LoadChartTab);
            app.BucketCylAreaLCEditFieldLabel.Tooltip = {'Area of bucket cylinder, rod end'};
            app.BucketCylAreaLCEditFieldLabel.Text = 'Bucket  (rod)';
            app.BucketCylAreaLCEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.BucketCylAreaLCEditField.Limits = [0 Inf];
            app.BucketCylAreaLCEditField.ValueDisplayFormat = '%11.0f';
            app.BucketCylAreaLCEditField.Tooltip = {'Area of bucket cylinder, rod end'};
            app.BucketCylAreaLCEditField.Value = 17907;
            app.CylinderAreamm2Label_2 = uilabel(app.LoadChartTab);
            app.CylinderAreamm2Label_2.Text = 'Cylinder Area (mm^2)';
            app.BoomMassLCEditFieldLabel = uilabel(app.LoadChartTab);
            app.BoomMassLCEditFieldLabel.Text = 'Boom ';
            app.StickMassLCEditFieldLabel = uilabel(app.LoadChartTab);
            app.StickMassLCEditFieldLabel.Text = 'Stick ';
            app.PartMasskgLabel = uilabel(app.LoadChartTab);
            app.PartMasskgLabel.Text = 'Part Mass (kg)';
            app.BoomMassLCEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.BoomMassLCEditField.Limits = [0 Inf];
            app.BoomMassLCEditField.ValueDisplayFormat = '%11.2f';
            app.BoomMassLCEditField.Tooltip = {'Mass of boom.'};
            app.BoomMassLCEditField.Value = 7226.51;
            app.StickMassLCEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.StickMassLCEditField.Limits = [0 Inf];
            app.StickMassLCEditField.ValueDisplayFormat = '%11.2f';
            app.StickMassLCEditField.Tooltip = {'Mass of stick.'};
            app.StickMassLCEditField.Value = 3327.88;
            app.BucketMassLCEditField = uieditfield(app.LoadChartTab, 'numeric');
            app.BucketMassLCEditField.Limits = [0 Inf];
            app.BucketMassLCEditField.ValueDisplayFormat = '%11.2f';
            app.BucketMassLCEditField.Tooltip = {'Mass of empty bucket. '; ' Load is added during test.'};
            app.BucketMassLCEditField.Value = 5119.51;
            app.BucketMassLCEditFieldLabel = uilabel(app.LoadChartTab);
            app.BucketMassLCEditFieldLabel.Text = 'Bucket ';
            app.OpenSetupModelButton = uibutton(app.Excavator_Design, 'push');
            app.OpenSetupModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSetupModelButtonPushed, true);
            app.OpenSetupModelButton.Tooltip = {'Open Simscape model in Simulink editor.'};
            app.OpenSetupModelButton.Text = 'Open Setup Model';
            app.OpenCalculateModelButton = uibutton(app.Excavator_Design, 'push');
            app.OpenCalculateModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCalculateModelButtonPushed, true);
            app.OpenCalculateModelButton.Tooltip = {'Open Simscape model in Simulink editor.'};
            app.OpenCalculateModelButton.Text = 'Open Calculate Model';
            app.SetupModelEditFieldLabel = uilabel(app.Excavator_Design);
            app.SetupModelEditFieldLabel.Tooltip = {'Simscape model of excavator to get test position.'};
            app.SetupModelEditFieldLabel.Text = 'Setup Model';
            app.SetupModelEditField = uieditfield(app.Excavator_Design, 'text');
            app.SetupModelEditField.Tooltip = {'Simscape model of excavator to get test position.'};
            app.SetupModelEditField.Value = 'Excavator_Param_BOFTOF_Setup.slx';
            app.CalculateModelEditFieldLabel = uilabel(app.Excavator_Design);
            app.CalculateModelEditFieldLabel.Tooltip = {'Simscape model of excavator to calculate dig and load forces.'};
            app.CalculateModelEditFieldLabel.Text = 'Calculate Model';
            app.CalculateModelEditField = uieditfield(app.Excavator_Design, 'text');
            app.CalculateModelEditField.Tooltip = {'Simscape model of excavator to calculate dig and load forces.'};
            app.CalculateModelEditField.Value = 'Excavator_Param_BOFTOF_Calc.slx';
            % Show the figure after all components are created
            app.Excavator_Design.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Excavator_Design_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Excavator_Design)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.Excavator_Design)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Excavator_Design)
        end
    end
end