classdef Excavator_Full_System_Test_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Excavator_Full_System_Test      matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SystemTab                       matlab.ui.container.Tab
        OpenButton                      matlab.ui.control.Button
        PlotSoilForceCodeButton         matlab.ui.control.Button
        PlotSoilForceButton             matlab.ui.control.Button
        PlotEnergyCodeButton            matlab.ui.control.Button
        PlotEnergyButton                matlab.ui.control.Button
        PlotPressuresCodeButton         matlab.ui.control.Button
        PlotPressuresButton             matlab.ui.control.Button
        PlotPositionsCodeButton         matlab.ui.control.Button
        PlotPositionsButton             matlab.ui.control.Button
        SimulateTestSequenceButton      matlab.ui.control.Button
        ModelEditField                  matlab.ui.control.EditField
        ModelEditFieldLabel             matlab.ui.control.Label
        PlotOperatorCommandsCodeButton  matlab.ui.control.Button
        PlotOperatorCommandsButton      matlab.ui.control.Button
        SystemSequenceDropDown          matlab.ui.control.DropDown
        SequenceDropDownLabel           matlab.ui.control.Label
        OperatorCmdAxes                 matlab.ui.control.UIAxes
        ExcavatorFullSystemTestLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changed function: SystemSequenceDropDown
        function SystemSequenceDropDownValueChanged(app, event)
            value = app.SystemSequenceDropDown.Value;
            str = ['Excavator_Test_Scenario_Select(''' value ''');'];
            disp(str);
            evalin('base',str)
        end

        % Button pushed function: PlotOperatorCommandsButton
        function PlotOperatorCommandsButtonPushed(app, event)
            SystemSequenceDropDownValueChanged(app, event)
            Excavator_Complete_plot1operatorcmds(app.OperatorCmdAxes)
        end

        % Button pushed function: PlotOperatorCommandsCodeButton
        function PlotOperatorCommandsCodeButtonPushed(app, event)
            edit Excavator_Complete_plot1operatorcmds.m
        end

        % Button pushed function: SimulateTestSequenceButton
        function SimulateTestSequenceButtonPushed(app, event)
            modelName = app.ModelEditField.Value;
            if(endsWith(modelName,'.slx'))
                modelName = modelName(1:end-4);
            end
            load_system([modelName '.slx']);
            simOut = sim(modelName);
            assignin('base','simOut',simOut);
        end

        % Button pushed function: PlotPositionsButton
        function PlotPositionsButtonPushed(app, event)
            str = 'Excavator_Complete_plot2position';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: PlotPositionsCodeButton
        function PlotPositionsCodeButtonPushed(app, event)
            edit Excavator_Complete_plot2position.m
        end

        % Button pushed function: PlotPressuresButton
        function PlotPressuresButtonPushed(app, event)
            str = 'Excavator_Complete_plot3pressure';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: PlotPressuresCodeButton
        function PlotPressuresCodeButtonPushed(app, event)
            edit Excavator_Complete_plot3pressure.m
        end

        % Button pushed function: PlotEnergyButton
        function PlotEnergyButtonPushed(app, event)
            str = 'Excavator_Energy_Calc';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: PlotEnergyCodeButton
        function PlotEnergyCodeButtonPushed(app, event)
            edit Excavator_Energy_Calc.m
        end

        % Button pushed function: PlotSoilForceButton
        function PlotSoilForceButtonPushed(app, event)
            str = 'Excavator_Complete_plot4loadsoil';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: PlotSoilForceCodeButton
        function PlotSoilForceCodeButtonPushed(app, event)
            edit Excavator_Complete_plot4loadsoil.m
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            open_system(app.ModelEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Excavator_Full_System_Test = uifigure('Visible', 'off');
            app.Excavator_Full_System_Test.Name = 'Excavator Full System Test';
            app.ExcavatorFullSystemTestLabel = uilabel(app.Excavator_Full_System_Test);
            app.ExcavatorFullSystemTestLabel.Text = 'Excavator Full System Test';
            app.TabGroup = uitabgroup(app.Excavator_Full_System_Test);
            app.SystemTab = uitab(app.TabGroup);
            app.SystemTab.Title = 'System';
            app.OperatorCmdAxes = uiaxes(app.SystemTab);
            title(app.OperatorCmdAxes, 'Operator Command')
            xlabel(app.OperatorCmdAxes, 'Time (s)')
            ylabel(app.OperatorCmdAxes, 'Command (-1, 1)')
            zlabel(app.OperatorCmdAxes, 'Z')
            app.SequenceDropDownLabel = uilabel(app.SystemTab);
            app.SequenceDropDownLabel.Text = 'Sequence';
            app.SystemSequenceDropDown = uidropdown(app.SystemTab);
            app.SystemSequenceDropDown.Items = {'Swing Left', 'Swing Right', 'Boom Up', 'Boom Down', 'Stick In', 'Stick Out', 'Bucket Close', 'Bucket Open', 'Dig Cycle, Empty', 'Dig Cycle, Loaded'};
            app.SystemSequenceDropDown.ItemsData = {'swingLeft', 'swingRight', 'boomUp', 'boomDown', 'stickIn', 'stickOut', 'bucketClose', 'bucketOpen', 'emptyBucketDigCycle', 'loadedBucketDigCycle'};
            app.SystemSequenceDropDown.ValueChangedFcn = createCallbackFcn(app, @SystemSequenceDropDownValueChanged, true);
            app.SystemSequenceDropDown.Tooltip = {'Select test sequence and load commands to MATLAB workspace.'};
            app.SystemSequenceDropDown.Value = 'swingLeft';
            app.PlotOperatorCommandsButton = uibutton(app.SystemTab, 'push');
            app.PlotOperatorCommandsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOperatorCommandsButtonPushed, true);
            app.PlotOperatorCommandsButton.Tooltip = {'Plot operator commands (lever positions) in App.'};
            app.PlotOperatorCommandsButton.Text = 'Plot Operator Commands';
            app.PlotOperatorCommandsCodeButton = uibutton(app.SystemTab, 'push');
            app.PlotOperatorCommandsCodeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOperatorCommandsCodeButtonPushed, true);
            app.PlotOperatorCommandsCodeButton.Tooltip = {'See code to plot operator commands.'};
            app.PlotOperatorCommandsCodeButton.Text = 'Code';
            app.ModelEditFieldLabel = uilabel(app.SystemTab);
            app.ModelEditFieldLabel.Text = 'Model';
            app.ModelEditField = uieditfield(app.SystemTab, 'text');
            app.ModelEditField.Value = 'Excavator_Complete.slx';
            app.SimulateTestSequenceButton = uibutton(app.SystemTab, 'push');
            app.SimulateTestSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateTestSequenceButtonPushed, true);
            app.SimulateTestSequenceButton.Tooltip = {'Start simulation.'};
            app.SimulateTestSequenceButton.Text = 'Simulate Test Sequence';
            app.PlotPositionsButton = uibutton(app.SystemTab, 'push');
            app.PlotPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPositionsButtonPushed, true);
            app.PlotPositionsButton.Tooltip = {'Plot actuator positions in separate figure.'};
            app.PlotPositionsButton.Text = 'Plot Positions';
            app.PlotPositionsCodeButton = uibutton(app.SystemTab, 'push');
            app.PlotPositionsCodeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPositionsCodeButtonPushed, true);
            app.PlotPositionsCodeButton.Tooltip = {'See code for position plot.'};
            app.PlotPositionsCodeButton.Text = 'Code';
            app.PlotPressuresButton = uibutton(app.SystemTab, 'push');
            app.PlotPressuresButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPressuresButtonPushed, true);
            app.PlotPressuresButton.Tooltip = {'Plot actuator pressures in separate figure.'};
            app.PlotPressuresButton.Text = 'Plot Pressures';
            app.PlotPressuresCodeButton = uibutton(app.SystemTab, 'push');
            app.PlotPressuresCodeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPressuresCodeButtonPushed, true);
            app.PlotPressuresCodeButton.Tooltip = {'See code for pressure plot.'};
            app.PlotPressuresCodeButton.Text = 'Code';
            app.PlotEnergyButton = uibutton(app.SystemTab, 'push');
            app.PlotEnergyButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEnergyButtonPushed, true);
            app.PlotEnergyButton.Tooltip = {'Plot energy input and output from system in separate figure. Designed for dig cycle sequence.'};
            app.PlotEnergyButton.Text = 'Plot Energy';
            app.PlotEnergyCodeButton = uibutton(app.SystemTab, 'push');
            app.PlotEnergyCodeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEnergyCodeButtonPushed, true);
            app.PlotEnergyCodeButton.Tooltip = {'See code for energy plot.'};
            app.PlotEnergyCodeButton.Text = 'Code';
            app.PlotSoilForceButton = uibutton(app.SystemTab, 'push');
            app.PlotSoilForceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSoilForceButtonPushed, true);
            app.PlotSoilForceButton.Tooltip = {'Plot forces of soil on bucket, including resistance and gravity, in separate figure. Use with dig cycle sequence.'};
            app.PlotSoilForceButton.Text = 'Plot Soil Force';
            app.PlotSoilForceCodeButton = uibutton(app.SystemTab, 'push');
            app.PlotSoilForceCodeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSoilForceCodeButtonPushed, true);
            app.PlotSoilForceCodeButton.Tooltip = {'See code for soil forces plot.'};
            app.PlotSoilForceCodeButton.Text = 'Code';
            app.OpenButton = uibutton(app.SystemTab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Open simulation model.'};
            app.OpenButton.Text = 'Open';
            % Show the figure after all components are created
            app.Excavator_Full_System_Test.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Excavator_Full_System_Test_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Excavator_Full_System_Test)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Excavator_Full_System_Test)
        end
    end
end